classdef mvpa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure                   % MVPA toolbox ...
        ui_start         matlab.ui.control.Button           % Start
        TabGroup         matlab.ui.container.TabGroup       % input, normal...
        Tab_input        matlab.ui.container.Tab            % input
        ui_data_type     matlab.ui.container.ButtonGroup    % data_type
        ui_isnii         matlab.ui.control.RadioButton      % nii
        ui_ismat         matlab.ui.control.RadioButton      % mat
        LabelEditField   matlab.ui.control.Label            % data directory:
        ui_data_path     matlab.ui.control.EditField       
        Button           matlab.ui.control.Button           % load data
        Button2          matlab.ui.control.Button           % load mask
        Label            matlab.ui.control.Label            % mask directory:
        ui_mask_path     matlab.ui.control.EditField       
        Button3          matlab.ui.control.Button           % load label
        Label2           matlab.ui.control.Label            % label directory:
        ui_label_path    matlab.ui.control.EditField       
        Tab_norm         matlab.ui.container.Tab            % normalization
        ui_norm_type     matlab.ui.control.DropDown         % Option 1, Opt...
        ui_isnorm        matlab.ui.control.CheckBox         % normalization
        Label5           matlab.ui.control.Label            % params:
        Tab_fs           matlab.ui.container.Tab            % feature selec...
        ui_isfs          matlab.ui.control.CheckBox         % feature selec...
        ui_fs_type       matlab.ui.control.DropDown         % Option 1, Opt...
        Label6           matlab.ui.control.Label            % params:
        Tab4             matlab.ui.container.Tab            % classifier
        ui_classify_type matlab.ui.control.DropDown         % Option 1, Opt...
        ui_kfold_type    matlab.ui.container.ButtonGroup    % cross validation
        ui_isloocv       matlab.ui.control.RadioButton      % LOOCV
        ui_iskfold       matlab.ui.control.RadioButton      % k-fold
        ui_kfold_num     matlab.ui.control.NumericEditField % [2 Inf]
        Label4           matlab.ui.control.Label            % classifier
        Label7           matlab.ui.control.Label            % params:
        Tab5             matlab.ui.container.Tab            % optimize
        CheckBox         matlab.ui.control.CheckBox         % parameter opt...
        TabGroup2        matlab.ui.container.TabGroup       % display, results
        Tab6             matlab.ui.container.Tab            % display
        display          matlab.ui.control.TextArea         % =============...
        Tab7             matlab.ui.container.Tab            % results
        ui_save          matlab.ui.control.Button           % save
        ui_load          matlab.ui.control.Button           % load
    end


    properties (Access = private)
        mp_data % input_data
        mp_label % label
    end

    properties (Access = public)
        mp_apppath % mvpa path
        mp_input_param % data_input parameters: data_type,
        mp_norm_param % data_normalization parameters: extra
        mp_fs_param % feature selection parameters
        mp_classify_param % classifier parameters
        mp_output_param % output parameters
        mp_ps_param % param search parameters
        mp_cv_param % cross validation parameters
        mp_output % Description
        mp_ui_norm_param % a struct storing the uis of norm parameters
        mp_ui_fs_param
        mp_ui_classify_param
        mp_ui_ps_param
    end

    methods (Access = private)
        
        function isok = mf_read_data(app)
            if(strcmp(app.mp_input_param.data_type,'nii'))
                [app.mp_data,app.mp_label] = mvpa_read_data_nii(app.mp_input_param);
            elseif(strcmp(app.mp_input_param.data_type,'mat'))
                [app.mp_data,app.mp_label] = mvpa_read_data_mat(app.mp_input_param);
            end
            isok = true;
        end

        function isok = mf_normlization(app)
            app.mp_data = mvpa_normalization(app.mp_data,app.mp_label,app.mp_norm_param.extra);
            isok = 1;
        end

        function [tp_train_idx,tp_test_idx] = mf_kfold(app)
            
        end

        function [tp_feature,tp_invert_param] = mf_feature_sel(app,tp_data,tp_label)
            if(app.mp_fs_param.isfs == 1)
                [tp_feature,tp_invert_param] = mvpa_feature_selection(tp_data,tp_label,app.fs_param.extra);
            else
                tp_feature = tp_data;
                tp_invert_param = eyes(size(tp_data,2));
            end
        end
        
        function tp_feature = mf_fs_apply(app,tp_data,tp_fs_param)
            if(app.mp_fs_param.isfs == 1)
                tp_feature = mvpa_fs_apply(tp_data,tp_fs_param);
            else
                tp_feature = tp_data;
            end
        end
        
        function isok = mf_classify(app,tp_train_idx,tp_test_idx)
            
            
        end
        
        function [tp_label_predict,tp_dvalue] = mf_classify_train_test(app,tp_data_train,tp_label_train,tp_data_test,tp_label_test,classify_param)
            model = mvpa_classify_train(tp_data_train,tp_label_train,classify_param);
            [tp_label_predict,tp_dvalue] = mvpa_classify_test(tp_data_test,tp_label_test,model,classify_param);
        end
        
        function isok = mf_add_norm_path(app)
            tp_norm_path = which('mvpa_normalization');
            while(tp_norm_path)
                rmpath(tp_norm_path(1:end-20));
                tp_norm_path = which('mvpa_normalization');
            end
            addpath([app.mp_apppath,'mvpa_normalization',filesep,app.mp_norm_param.norm_type]);
            isok = 1;
        end
        
        function isok = mf_add_fs_path(app)
            tp_fs_path = which('mvpa_feature_selection');
            while(tp_fs_path)
                rmpath(tp_fs_path(1:end-22));
                tp_fs_path = which('mvpa_feature_selection');
            end
            addpath([app.mp_apppath,'mvpa_feature_selection',filesep,app.mp_fs_param.fs_type]);
            isok = 1;
        end

        function isok = mf_add_classify_path(app)
            tp_classify_path = which('mvpa_classify');
            while(tp_classify_path)
                rmpath(tp_classify_path(1:end-13));
                tp_classify_path = which('mvpa_classify');
            end
            addpath([app.mp_apppath,'mvpa_classify',filesep,app.mp_classify_param,classify_type]);
            isok = 1;
        end
        
        function isok = mf_add_ps_path(app)
            tp_ps_path = which('mvpa_param_search');
            while(tp_ps_path)
                rmpath(tp_ps_path(1:end-17));
                tp_ps_path = which('mvpa_param_search');
            end
            addpath([app.mp_apppath,'mvpa_param_search',filesep,app.mp_ps_param,ps_type]);
            isok = 1;
        end

        function isok = mf_gen_ui_norm_param(app)
            if(~app.ui_isnorm.Value)
                isok = 1;
                return;
            end
            
            tmp_i = 1;
            while(isfield(app.mp_ui_norm_param,['ui_param_Label',num2str(tmp_i)]))
                eval(['delete(app.mp_ui_norm_param.ui_param_Label',num2str(tmp_i),');']);
                eval(['delete(app.mp_ui_norm_param.ui_param_EditField',num2str(tmp_i),');']);
                app.mp_ui_norm_param = [];
                tmp_i = tmp_i+1;
            end
            
            if(exist([app.mp_apppath,'mvpa_normalization',filesep...
                    app.ui_norm_type.Value,...
                    filesep,'mvpa_normalization_param.txt'],...
                    'file'))
                fid = fopen([app.mp_apppath,'mvpa_normalization',filesep,...
                    app.ui_norm_type.Value,...
                    filesep,'mvpa_normalization_param.txt'],'r');
                param_num_max = str2num(fgetl(fid));
                for i = 1:param_num_max
                    param_str = fgetl(fid);
                    param_num = fgetl(fid);

                    eval(['app.mp_ui_norm_param.ui_param_Label',num2str(i),...
                        ' = uilabel(app.Tab_norm);']);
                    eval(['app.mp_ui_norm_param.ui_param_Label',num2str(i),...
                        '.HorizontalAlignment = ''right'';']);
                    eval(['app.mp_ui_norm_param.ui_param_Label',num2str(i),...
                        '.Position = [100 ',num2str(450-i*50),' 100 20];']);
                    eval(['app.mp_ui_norm_param.ui_param_Label',num2str(i),...
                        '.Text = ''',param_str,':'';']);

                    eval(['app.mp_ui_norm_param.ui_param_EditField',num2str(i),...
                        ' = uieditfield(app.Tab_norm,''text'');']);
                    eval(['app.mp_ui_norm_param.ui_param_EditField',num2str(i),...
                        '.Position = [220 ',num2str(450-i*50),' 100 20];']);
                    eval(['app.mp_ui_norm_param.ui_param_EditField',num2str(i),...
                        '.Value = ''',param_num,''';']);
                end
                fclose(fid);
            end
                
            if(exist([app.mp_apppath,'mvpa_normalization',filesep...
                    app.ui_norm_type.Value,...
                    filesep,'mvpa_normalization_intro.txt'],...
                    'file'))
                fid = fopen([app.mp_apppath,'mvpa_normalization',filesep,...
                    app.ui_norm_type.Value,...
                    filesep,'mvpa_normalization_intro.txt'],'r');
                tmp_str = fgetl(fid);
                while(ischar(tmp_str))
                    app.display.Value = [app.display.Value;tmp_str];
                    tmp_str = fgetl(fid);
                end
                fclose(fid);
            end
            isok = 1;
        end
        
        function isok = mf_gen_ui_fs_param(app)
            if(~app.ui_isfs.Value)
                isok = 1;
                return;
            end
            if(exist([app.mp_apppath,'mvpa_feature_selection',filesep,...
                    app.ui_fs_type.Value,...
                    filesep,'mvpa_feature_selection_intro.txt'],...
                    'file'))
                fid = fopen([app.mp_apppath,'mvpa_feature_selection',filesep,...
                    app.ui_fs_type.Value,...
                    filesep,'mvpa_feature_selection_intro.txt'],'r');
                tmp_str = fgetl(fid);
                while(ischar(tmp_str))
                    app.display.Value = [app.display.Value;tmp_str];
                    tmp_str = fgetl(fid);
                end
                fclose(fid);
            end
            isok = 1;
        end
        
        function isok = mf_gen_ui_classify_param(app)
            if(exist([app.mp_apppath,'mvpa_classify',filesep,...
                    app.ui_classify_type.Value,...
                    filesep,'mvpa_classify_intro.txt'],...
                    'file'))
                fid = fopen([app.mp_apppath,'mvpa_classify',filesep,...
                    app.ui_classify_type.Value,...
                    filesep,'mvpa_classify_intro.txt'],'r');
                tmp_str = fgetl(fid);
                while(ischar(tmp_str))
                    app.display.Value = [app.display.Value;tmp_str];
                    tmp_str = fgetl(fid);
                end
                fclose(fid);
            end
            isok = 1;
        end

        function isok = mf_read_param(app)
            % input params
            if(app.ui_isnii.Value == true)
                app.mp_input_param.data_type = 'nii';
            elseif(app.ui_ismat.Value == true)
                app.mp_input_param.data_type = 'mat';
            end
            app.mp_input_param.data_path = app.ui_data_path.Value;
            app.mp_input_param.mask_path = app.ui_mask_path.Value;
            app.mp_input_param.label_path = app.ui_label_path.Value;
            
            % normalization params
            if(app.ui_isnorm.Value)
                app.mp_norm_param.isnorm = 1;
            else
                app.mp_norm_param.isnorm = 0;
            end
            app.mp_norm_param.norm_type = app.ui_norm_type.Value;
            tmp_i = 1;
            while(isfield(app.mp_ui_norm_param,['ui_param_Label',num2str(tmp_i)]))
                eval(['app.mp_norm_param.extra(',num2str(tmp_i),...
                    ') = app.mp_ui_norm_param.ui_param_EditField',num2str(tmp_i),'.Value']);
                tmp_i = tmp_i+1;
            end
            
            % feature selection params
            if(app.ui_isfs.Value)
                app.mp_fs_param.isfs = 1;
            else
                app.mp_fs_param.isfs = 0;
            end
            app.mp_fs_param.fs_type = app.ui_fs_type.Value;
            tmp_i = 1;
            while(isfield(app.mp_ui_fs_param,['ui_param_Label',num2str(tmp_i)]))
                eval(['app.mp_fs_param.extra(',num2str(tmp_i),...
                    ') = app.mp_ui_fs_param.ui_param_EditField',num2str(tmp_i),'.Value']);
                tmp_i = tmp_i+1;
            end
            
            % classifer params
            if(app.ui_isloocv.Value)
                app.mp_cv_param.cv_type = 'loocv';
            elseif(app.ui_iskfold.Value)
                app.mp_cv_param.cv_type = 'kfold';
                app.mp_cv_param.kfold_num = app.ui_kfold_num.Value;
            end
            
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.mp_apppath = which('mvpa');
            app.mp_apppath(end-9:end) = [];
            app.display.Value = 'welcome!';
            
            norm_types = dir([app.mp_apppath,'mvpa_normalization']);
            norm_types(1:2) = [];
            for i = 1:length(norm_types)
                norm_type_str{i} = norm_types(i).name;
            end
            app.ui_norm_type.Items = norm_type_str;
            
            fs_types = dir([app.mp_apppath,'mvpa_feature_selection']);
            fs_types(1:2) = [];
            for i = 1:length(fs_types)
                fs_type_str{i} = fs_types(i).name;
            end
            app.ui_fs_type.Items = fs_type_str;
            
            classify_types = dir([app.mp_apppath,'mvpa_classify']);
            classify_types(1:2) = [];
            for i = 1:length(classify_types)
                classify_type_str{i} = classify_types(i).name;
            end
            app.ui_classify_type.Items = classify_type_str;
            
        end

        % ui_start button pushed function
        function ui_startButtonPushed(app)
            
            % 读取 GUI界面参数
            mf_read_param(app);
            
            % 读取数据，存放至app.mf_data,app.mf_label
            mf_read_data(app);
            
            % normalization
            if(app.mp_norm_param.isnorm == 1)
                % 添加mvpa_normalization
                app.add_norm_path(app);
                mf_normalization(app);
            end
            
            % feature selection
            if(app.mp_fs_param.isfs == 1)
                app.add_fs_path(app);
            end
            
            % parameter optimize
            if(app.mp_ps_param.isps == 1)
                app.add_ps_path(app);
            end
            
            % main part 重复n次取平均,loocv的repeat==1
            for i_repeat = 1:app.mp_cv_param.repeat
                % k-fold,loocv视为kfold的一种
                [tp_train_idx,tp_test_idx] = mf_kfold(app);

                % 分类
                for i =1:length(tp_train_idx)
                    % 构建trainingdata，testingdata
                    tp_data_train = app.mp_data(tp_train_idx{i},:);
                    tp_label_train = app.mp_label(tp_train_idx{i},:);
                    tp_data_test = app.mp_data(tp_test_idx{i},:);
                    tp_label_test = app.mp_label(tp_test_idx{i},:);
                    
                    if(strcmp(app.mf_ps_param.ps_fnum_type,'range') && (app.mf_ps_param.isps == 0))
                        [tp_feature_train,tp_fs_param] = mf_feature_sel(app,tp_data_train,tp_label);
                        tp_feature_test = mf_fs_apply(app,tp_data_test,tp_fs_param);
                        j_num = 0;
                        for j = app.mf_ps_param.ps_fnum_min:app.mf_ps_param.ps_fnum_step:app.mf_ps_param.ps_fnum_max
                            j_num = j_num+1;
                            [tp_label_predict(tp_test_idx{i},j_num),tp_dvalue(tp_test_idx{i},j_num)] = ...
                                mf_classify_train_test(app,tp_feature_train(:,1:j),tp_label_train,...
                                tp_feature_test(:,1:j),tp_label_test,mf_ps_param.extra);
                        end
                        
                    elseif(strcmp(app.mf_ps_param.ps_fnum_type,'range') && (app.mf_ps_param.isps == 1))
                        
                    elseif(strcmp(app.mf_ps_param.ps_fnum_type,'cv2') && (app.mf_ps_param.isps == 1))
                        
                    end
                end
                
                if(strcmp(app.mf_ps_param.ps_fnum_type,'range'))
                    for i = 1:size(tp_label_predict,2)
                        app.mp_output.acc_range(i) = sum(tp_label_precit(:,i) == app.mp_label)./length(app.mp_label);
                    end
                    fnum_best = find(app.mp_output.acc_range == max(app.mp_output.acc_range),1,'first');
                    app.mp_output.acc_best = app.mp_output.acc_range(fnum_best);
                end
            end
            % 平均结果输出至mp_output
            
            
        end

        % ui_isnorm value changed function
        function ui_isnormValueChanged(app)
            value = app.ui_isnorm.Value;
            if(value)
                app.ui_norm_type.Enable = 'on';
            else
                app.ui_norm_type.Enable = 'off';
            end            
            mf_gen_ui_norm_param(app);
        end

        % ui_norm_type value changed function
        function ui_norm_typeValueChanged(app, event)
            value = app.ui_norm_type.Value;
            mf_gen_ui_norm_param(app);
        end

        % ui_isfs value changed function
        function ui_isfsValueChanged(app)
            value = app.ui_isfs.Value;
            if(value)
                app.ui_fs_type.Enable = 'on';
            else
                app.ui_fs_type.Enable = 'off';
            end   
            mf_gen_ui_fs_param(app);
        end

        % ui_fs_type value changed function
        function ui_fs_typeValueChanged(app, event)
            value = app.ui_fs_type.Value;
            mf_gen_ui_fs_param(app);
        end

        % ui_classify_type value changed function
        function ui_classify_typeValueChanged(app, event)
            value = app.ui_classify_type.Value;
            mf_gen_ui_classify_param(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MVPA toolbox by Heng';
            setAutoResize(app, app.UIFigure, true)
            app.ui_start = uibutton(app.UIFigure, 'push');
            app.ui_start.ButtonPushedFcn = createCallbackFcn(app, @ui_startButtonPushed);
            app.ui_start.Text = 'Start';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.Tab_input = uitab(app.TabGroup);
            app.Tab_input.Units = 'pixels';
            app.Tab_input.Title = 'input';
            app.ui_data_type = uibuttongroup(app.Tab_input);
            app.ui_data_type.Title = 'data_type';
            app.ui_data_type.Units = 'pixels';
            app.ui_isnii = uiradiobutton(app.ui_data_type);
            app.ui_isnii.Text = 'nii';
            app.ui_ismat = uiradiobutton(app.ui_data_type);
            app.ui_ismat.Value = true;
            app.ui_ismat.Text = 'mat';
            app.LabelEditField = uilabel(app.Tab_input);
            app.LabelEditField.Text = 'data directory:';
            app.ui_data_path = uieditfield(app.Tab_input, 'text');
            app.Button = uibutton(app.Tab_input, 'push');
            app.Button.Text = 'load data';
            app.Button2 = uibutton(app.Tab_input, 'push');
            app.Button2.Text = 'load mask';
            app.Label = uilabel(app.Tab_input);
            app.Label.Text = 'mask directory:';
            app.ui_mask_path = uieditfield(app.Tab_input, 'text');
            app.Button3 = uibutton(app.Tab_input, 'push');
            app.Button3.Text = 'load label';
            app.Label2 = uilabel(app.Tab_input);
            app.Label2.Text = 'label directory:';
            app.ui_label_path = uieditfield(app.Tab_input, 'text');
            app.Tab_norm = uitab(app.TabGroup);
            app.Tab_norm.Units = 'pixels';
            app.Tab_norm.Title = 'normalization';
            app.ui_norm_type = uidropdown(app.Tab_norm);
            app.ui_norm_type.ValueChangedFcn = createCallbackFcn(app, @ui_norm_typeValueChanged, true);
            app.ui_norm_type.Enable = 'off';
            app.ui_isnorm = uicheckbox(app.Tab_norm);
            app.ui_isnorm.ValueChangedFcn = createCallbackFcn(app, @ui_isnormValueChanged);
            app.ui_isnorm.Text = 'normalization';
            app.Label5 = uilabel(app.Tab_norm);
            app.Label5.Text = 'params:';
            app.Tab_fs = uitab(app.TabGroup);
            app.Tab_fs.Units = 'pixels';
            app.Tab_fs.Title = 'feature selection';
            app.ui_isfs = uicheckbox(app.Tab_fs);
            app.ui_isfs.ValueChangedFcn = createCallbackFcn(app, @ui_isfsValueChanged);
            app.ui_isfs.Text = 'feature selection';
            app.ui_fs_type = uidropdown(app.Tab_fs);
            app.ui_fs_type.ValueChangedFcn = createCallbackFcn(app, @ui_fs_typeValueChanged, true);
            app.ui_fs_type.Enable = 'off';
            app.Label6 = uilabel(app.Tab_fs);
            app.Label6.Text = 'params:';
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Units = 'pixels';
            app.Tab4.Title = 'classifier';
            app.ui_classify_type = uidropdown(app.Tab4);
            app.ui_classify_type.ValueChangedFcn = createCallbackFcn(app, @ui_classify_typeValueChanged, true);
            app.ui_kfold_type = uibuttongroup(app.Tab4);
            app.ui_kfold_type.Title = 'cross validation';
            app.ui_kfold_type.Units = 'pixels';
            app.ui_isloocv = uiradiobutton(app.ui_kfold_type);
            app.ui_isloocv.Value = true;
            app.ui_isloocv.Text = 'LOOCV';
            app.ui_iskfold = uiradiobutton(app.ui_kfold_type);
            app.ui_iskfold.Text = 'k-fold';
            app.ui_kfold_num = uieditfield(app.ui_kfold_type, 'numeric');
            app.ui_kfold_num.Limits = [2 Inf];
            app.ui_kfold_num.Value = 5;
            app.Label4 = uilabel(app.Tab4);
            app.Label4.Text = 'classifier';
            app.Label7 = uilabel(app.Tab4);
            app.Label7.Text = 'params:';
            app.Tab5 = uitab(app.TabGroup);
            app.Tab5.Units = 'pixels';
            app.Tab5.Title = 'optimize';
            app.CheckBox = uicheckbox(app.Tab5);
            app.CheckBox.Text = 'parameter optimization';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.TabGroup2.Units = 'pixels';
            app.Tab6 = uitab(app.TabGroup2);
            app.Tab6.Units = 'pixels';
            app.Tab6.Title = 'display';
            app.display = uitextarea(app.Tab6);
            app.display.Value = {'==================welcome!==================='};
            app.Tab7 = uitab(app.TabGroup2);
            app.Tab7.Units = 'pixels';
            app.Tab7.Title = 'results';
            app.ui_save = uibutton(app.UIFigure, 'push');
            app.ui_save.Text = 'save';
            app.ui_load = uibutton(app.UIFigure, 'push');
            app.ui_load.Text = 'load';
        end
    end

    methods (Access = public)

        % Construct app
        function app = mvpa()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end